classdef moreSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AlgorithmsSampletimePanel   matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        SaveButton                  matlab.ui.control.Button
        OtherSensorssEditField      matlab.ui.control.NumericEditField
        OtherSensorsLabel           matlab.ui.control.Label
        GyroscopesEditField         matlab.ui.control.NumericEditField
        GyroscopesEditFieldLabel    matlab.ui.control.Label
        ControlalgorithmsEditField  matlab.ui.control.NumericEditField
        ControlalgorithmLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description

    end
    
    methods (Access = private)
        
         function updateInterface(app, data)
             app.ControlalgorithmsEditField.Value = data.control.Ts;
             app.GyroscopesEditField.Value = data.sensors.gyro.Ts;
             app.OtherSensorssEditField.Value = data.sensors.attitude.Ts;
         end

         function newData = updateParameters(app)
             newData.control.Ts = app.ControlalgorithmsEditField.Value;
             newData.sensors.gyro.Ts = app.GyroscopesEditField.Value;
             newData.sensors.attitude.Ts = app.OtherSensorssEditField.Value;

             newData.i = 8;
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp, dataToSend);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlgorithmsSampletimePanel = uipanel(app.UIFigure);
            app.AlgorithmsSampletimePanel.Title = 'Algorithms Sample time';
            app.GridLayout = uigridlayout(app.AlgorithmsSampletimePanel);
            app.ControlalgorithmLabel = uilabel(app.GridLayout);
            app.ControlalgorithmLabel.Text = 'Control algorithm (s): ';
            app.ControlalgorithmsEditField = uieditfield(app.GridLayout, 'numeric');
            app.GyroscopesEditFieldLabel = uilabel(app.GridLayout);
            app.GyroscopesEditFieldLabel.Text = 'Gyroscope (s):';
            app.GyroscopesEditField = uieditfield(app.GridLayout, 'numeric');
            app.OtherSensorsLabel = uilabel(app.GridLayout);
            app.OtherSensorsLabel.Text = 'Other Sensors (s):';
            app.OtherSensorssEditField = uieditfield(app.GridLayout, 'numeric');
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moreSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end